classdef game1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        InstructionsPanel       matlab.ui.container.Panel
        HowtoPlayLabel          matlab.ui.control.Label
        TextArea                matlab.ui.control.TextArea
        BacktoHomeButton        matlab.ui.control.Button
        PlayPanel               matlab.ui.container.Panel
        WordCountdownLabel      matlab.ui.control.Label
        SubmitButton            matlab.ui.control.Button
        EditField5              matlab.ui.control.EditField
        EditField4              matlab.ui.control.EditField
        EditField3              matlab.ui.control.EditField
        EditField2              matlab.ui.control.EditField
        EditField               matlab.ui.control.EditField
        Button5                 matlab.ui.control.Button
        Button4                 matlab.ui.control.Button
        Button3                 matlab.ui.control.Button
        Button2                 matlab.ui.control.Button
        Button                  matlab.ui.control.Button
        GameModePanel           matlab.ui.container.Panel
        BeginButton             matlab.ui.control.Button
        HowtoPlayButton         matlab.ui.control.Button
        HomeLabel               matlab.ui.control.Label
        WelcomePanel            matlab.ui.container.Panel
        CLPS0950March2025Label  matlab.ui.control.Label
        ByKiaraIbanezDiazandOliviaWangLabel  matlab.ui.control.Label
        Button_2                matlab.ui.control.Button
        WELCOMETOLabel          matlab.ui.control.Label
    end

   %edited by olivia March 17, 2025 12:11AM-1:27AM
    %objectives:
        %added comments denoted where kiara wrote code (all of it lol) 
        %did not debug buttons sorry kiara also i feel like i fucked up the
        %connection from the begin button to the play panel
        %add instructions page (completed...needs work on BacktoHome
        %button linking) 
        %add gif title to welcome page (completed) 

    %kiara
    properties (Access = private)
        DraggingTile  matlab.ui.control.Button
        WordList string
        Dictionary;
        CorrectWord;
        wordSlots 
        PossibleWords
        PossibleWordsCount
        FoundWords
        letterButtons
   
    end
    
    methods (Access = private)
        %kiara
         function startupFcn(app)
            app.wordSlots = [app.EditField, app.EditField2, app.EditField3, app.EditField4, app.EditField5];
            
            % Style edit fields to be more visually distinct
            for i = 1:length(app.wordSlots)
                app.wordSlots(i).BackgroundColor = [0.9 0.9 0.9]; % Light gray background
                app.wordSlots(i).HorizontalAlignment = 'center';  % Center the text
                app.wordSlots(i).FontSize = 14;                   % Make text visible
            end
            
            % Style letter buttons
            app.letterButtons = [app.Button, app.Button2, app.Button3, app.Button4, app.Button5];
            for i = 1:length(app.letterButtons)
                app.letterButtons(i).BackgroundColor = [0.8 0.8 1];   % Light blue
                app.letterButtons(i).FontSize = 16;                   % Larger font
                app.letterButtons(i).FontWeight = 'bold';             % Bold text
                
                % Initialize UserData as struct for all buttons
                app.letterButtons(i).UserData = struct('slotIndex', [], 'originalPosition', app.letterButtons(i).Position);
            end
            
            app.BacktoHomeButton.ButtonPushedFcn = createCallbackFcn(app, @BacktoHomeButtonPushed, true);
            showPanel(app, app.WelcomePanel);
        end
        
        function showPanel(app, panel)
            % Hide all panels
            app.WelcomePanel.Visible = 'off';
            app.GameModePanel.Visible = 'off';
            app.PlayPanel.Visible = 'off';
            app.InstructionsPanel.Visible = 'off';

            % Show the selected panel
            panel.Visible = 'on';
        end

        function loadDictionary(app)
            persistent dictionary;
            if isempty(dictionary)
                fid = fopen('words.txt');
                dictionary = textscan(fid, '%s');
                dictionary = dictionary{1};
                fclose(fid);
            end
            app.Dictionary = dictionary;
        end
        
        function checkWord(app)
            % Extract the letters from the current row
            wordLength = length(app.PossibleWords{1});
            currentWordIdx = 0;
            
            for i = 1:length(app.wordSlots)/wordLength
                rowStart = (i-1) * wordLength + 1;
                rowEnd = min(i * wordLength, length(app.wordSlots));
                rowFilled = true;
                
                for j = rowStart:rowEnd
                    if j > length(app.wordSlots) || isempty(app.wordSlots(j).Value)
                        rowFilled = false;
                        break;
                    end
                end
                
                if ~rowFilled
                    currentWordIdx = i;
                    break;
                end
            end
            
            if currentWordIdx == 0
                uialert(app.UIFigure, 'Complete a word before submitting.', 'No Complete Word');
                return;
            end
            
            % Extract the letters from the current row
            formedWord = '';
            rowStart = (currentWordIdx-1) * wordLength + 1;
            rowEnd = currentWordIdx * wordLength;
            
            for i = rowStart:rowEnd
                formedWord = [formedWord, app.wordSlots(i).Value];
            end
            
            % Convert to uppercase for consistent comparison
            formedWord = upper(formedWord);
            
            % Check if the word is one of the possible words
            isCorrect = false;
            wordIndex = 0;

            for i = 1:length(app.PossibleWords)
                if strcmp(formedWord, app.PossibleWords{i})
                    isCorrect = true;
                    wordIndex = i;
                    break;
                end
            end
            
            alreadyFound = false;
            if ~isempty(app.FoundWords)
                for i = 1:length(app.FoundWords)
                    if strcmp(formedWord, app.FoundWords{i})
                        alreadyFound = true;
                        break;
                    end
                end
            end

            if alreadyFound
                uialert(app.UIFigure, 'You already found this word!', 'Duplicate');
                
                % Clear the row
                for j = rowStart:rowEnd
                    app.wordSlots(j).Value = '';
                end
                
                % Return tiles to original positions
                for i = 1:length(app.letterButtons)
                    button = app.letterButtons(i);
                    % Make sure button.UserData is a struct and slotIndex exists
                    if isstruct(button.UserData) && isfield(button.UserData, 'slotIndex') && ~isempty(button.UserData.slotIndex)
                        if any(button.UserData.slotIndex >= rowStart & button.UserData.slotIndex <= rowEnd)
                            button.Position = button.UserData.originalPosition;
                            button.UserData.slotIndex = [];
                            button.BackgroundColor = [0.8 0.8 1];
                        end
                    end
                end
                
                return;
            end

            if isCorrect
                % Mark this word as found
                app.FoundWords{end+1} = formedWord;
                
                % Remove from the possible words list
                app.PossibleWords(wordIndex) = [];
                app.PossibleWordsCount = app.PossibleWordsCount - 1;
                
                % Update the counter
                app.WordCountdownLabel.Text = ['Words to find: ' num2str(app.PossibleWordsCount)];
                
                % Check for game completion
                if app.PossibleWordsCount == 0
                    uialert(app.UIFigure, 'Congratulations! You found all possible words!', 'Game Complete');
                else
                    uialert(app.UIFigure, ['Correct word: ' formedWord], 'Success');
                end
                
                % Clear the row
                for j = rowStart:rowEnd
                    app.wordSlots(j).Value = '';
                    app.wordSlots(j).BackgroundColor = [0.9 0.9 0.9];
                end
                
                % Return tiles to original positions
                for i = 1:length(app.letterButtons)
                    button = app.letterButtons(i);
                    % Check if UserData is a struct and has slotIndex field
                    if isstruct(button.UserData) && isfield(button.UserData, 'slotIndex') && ~isempty(button.UserData.slotIndex)
                        if any(button.UserData.slotIndex >= rowStart & button.UserData.slotIndex <= rowEnd)
                            button.Position = button.UserData.originalPosition;
                            button.UserData.slotIndex = [];
                            button.BackgroundColor = [0.8 0.8 1];
                        end
                    end
                end
            else
                uialert(app.UIFigure, 'Not a valid word from the available letters.', 'Try Again');
                
                % Clear the row
                for j = rowStart:rowEnd
                    app.wordSlots(j).Value = '';
                end
                
                % Return tiles to original positions
                for i = 1:length(app.letterButtons)
                    button = app.letterButtons(i);
                    % Check if UserData is a struct and has slotIndex field
                    if isstruct(button.UserData) && isfield(button.UserData, 'slotIndex') && ~isempty(button.UserData.slotIndex)
                        if any(button.UserData.slotIndex >= rowStart & button.UserData.slotIndex <= rowEnd)
                            button.Position = button.UserData.originalPosition;
                            button.UserData.slotIndex = [];
                            button.BackgroundColor = [0.8 0.8 1];
                        end
                    end
                end
            end
        end

        % Function to check if the formed word is valid
        function [dictionary, isValid] = isValidWord(app)
            persistent dict;
            
            if isempty(dict)
                % Load the dictionary only once
                fid = fopen('words.txt');
                dict = textscan(fid, '%s');
                dict = dict{1};  % Extract the cell array from the textscan result
                fclose(fid);
            end
            
            dictionary = dict;  % Return the dictionary
            
            % You can check validity here if needed
            isValid = @(word) any(strcmpi(word, dictionary));  % Check if the word is in the list
        end

        function findPossibleWords(app,letters)
            app.PossibleWords = {};
            app.FoundWords = {};
            letters = upper(letters);
            sortedLetters = sort(letters);

            for i = 1:length(app.Dictionary)
                word = upper(app.Dictionary{i});

                if length(word) ~= length(letters)
                    continue;
                end

                if strcmp(sort(word), sortedLetters)
                    app.PossibleWords{end+1} = word;
                end
            end

            app.PossibleWordsCount = length(app.PossibleWords);
            disp(['Found ', num2str(app.PossibleWordsCount), ' possible words:']);
            for i = 1:app.PossibleWordsCount
                disp(app.PossibleWords{i});
            end
        end

        function createWordSlots(app, wordLength)
            if ~isempty(app.wordSlots)
                for i = 1:length(app.wordSlots)
                    if isvalid(app.wordSlots(i))
                        delete(app.wordSlots(i));
                    end
                end
            end

            possibleWordsCount = app.PossibleWordsCount;
            
            startY = app.PlayPanel.Position(4) - 80;
            
            slotWidth = 34;
            slotHeight = 32;
            horizontalGap = 5;
            verticalGap = 10;

            wordWidth = wordLength * slotWidth + (wordLength - 1) * horizontalGap;
            startX = (app.PlayPanel.Position(3) - (wordLength * slotWidth + (wordLength-1) * horizontalGap)) /2;
            
            app.wordSlots = gobjects(1, app.PossibleWordsCount * wordLength);
            
            for wordIdx = 1:app.PossibleWordsCount
                for letterIdx = 1:wordLength
                    xPos = startX + (letterIdx-1) * (slotWidth + horizontalGap);
                    yPos = startY - (wordIdx-1) * (slotHeight + verticalGap);
                    
                    slotIndex = (wordIdx-1) * wordLength + letterIdx;
                    
                    slot = uieditfield(app.PlayPanel, 'text');
                    slot.Position = [xPos, yPos, slotWidth, slotHeight];
                    slot.Tag = ['WordSlot_' num2str(wordIdx) '_' num2str(letterIdx)];
                    slot.HorizontalAlignment = 'center';
                    slot.FontSize = 14;
                    slot.BackgroundColor = [0.9 0.9 0.9];
                    slot.Editable = false;
                    
                    % Store the slot in the array
                    app.wordSlots(slotIndex) = slot;
                end
                
                label = uilabel(app.PlayPanel);
                label.Position = [startX - 25, startY - (wordIdx-1) * (slotHeight + verticalGap), 20, slotHeight];
                label.Text = num2str(wordIdx);
                label.HorizontalAlignment = 'right';
            end
            
            % Update the word countdown label
            app.WordCountdownLabel.Text = ['Words to find: ' num2str(app.PossibleWordsCount)];
        end

        function createLetterButtons(app, scrambledWord)
            % Delete existing letter buttons if any
            if isfield(app, 'letterButtons') && ~isempty(app.letterButtons)
                for i = 1:length(app.letterButtons)
                    if isvalid(app.letterButtons(i))
                        delete(app.letterButtons(i));
                    end
                end
            end
            
            % Initialize empty array for letter buttons
            app.letterButtons = [];
            
            % Get the number of letters
            letterCount = length(scrambledWord);
            
            % Calculate button size and spacing
            buttonWidth = 35;
            buttonHeight = 35;
            gap = 10;
            totalWidth = buttonWidth * letterCount + gap * (letterCount - 1);
            startX = (app.PlayPanel.Position(3) - totalWidth) / 2;
            startY = 50; % Fixed Y position for the letter buttons
            
            % Create letter buttons
            for i = 1:letterCount
                % Calculate position
                xPos = startX + (i-1) * (buttonWidth + gap);
                
                % Create the button
                button = uibutton(app.PlayPanel, 'push');
                button.Position = [xPos, startY, buttonWidth, buttonHeight];
                button.Text = scrambledWord(i);
                button.Tag = ['LetterButton_', num2str(i)];
                button.BackgroundColor = [0.8 0.8 1];
                button.FontSize = 16;
                button.FontWeight = 'bold';
                
                % Initialize UserData as struct with necessary fields
                button.UserData = struct('originalPosition', [xPos, startY, buttonWidth, buttonHeight], 'slotIndex', []);
                
                % Add button click handler
                button.ButtonPushedFcn = createCallbackFcn(app, @letterButtonClicked, true);
                
                % Add to the letter buttons array
                app.letterButtons(i) = button;
            end
        end

        function checkRowCompletion(app, rowIndex)
            wordLength = length(app.PossibleWords{1});
            
            % Check if all slots in the row are filled
            rowStart = (rowIndex - 1) * wordLength + 1;
            rowEnd = rowIndex * wordLength;
            
            if rowEnd > length(app.wordSlots)
                return;
            end
            
            rowComplete = true;
            
            for i = rowStart:rowEnd
                if isempty(app.wordSlots(i).Value)
                    rowComplete = false;
                    break;
                end
            end
            
            if rowComplete
                % Auto-submit the word
                app.checkWord();
            end
        end

        function generateRandomLetters(app, wordLength)
            if nargin < 2
                wordLength = randi([4, 7]); % Random length between 4 and 7
            end

            disp(['Generating random letters for word length: ', num2str(wordLength)]);

            if isempty(app.Dictionary)
                app.loadDictionary();
            end
            
            wordsOfLength = app.Dictionary(cellfun(@length, app.Dictionary) == wordLength);

            if isempty(wordsOfLength)
                error(['No ', num2str(wordLength), '-letter words found in the dictionary']);    
            end
            
            % Select a random word
            randomIndex = randi(length(wordsOfLength));
            selectedWord = upper(wordsOfLength{randomIndex});
            
            % Store the correct word (unscrambled)
            app.CorrectWord = selectedWord;

            app.findPossibleWords(selectedWord);

            app.createWordSlots(wordLength);
            
            % Scramble the letters
            scrambledIndices = randperm(wordLength);
            scrambledWord = selectedWord(scrambledIndices);
            
            % Ensure the scrambled word is different from the original word
            while strcmp(scrambledWord, selectedWord)
                scrambledIndices = randperm(wordLength);
                scrambledWord = selectedWord(scrambledIndices);
            end

            app.createLetterButtons(scrambledWord);

            disp(['Original word:', selectedWord]);
            disp(['Scrambled word:', scrambledWord]);
            disp(['Possible words:', num2str(app.PossibleWordsCount)]);

            drawnow;
        end

        function startTileDrag(app, src, ~)
            if isa(src, 'matlab.ui.control.Button')
                app.DraggingTile = src;

                % Make sure UserData is initialized as a struct
                if ~isstruct(app.DraggingTile.UserData)
                    app.DraggingTile.UserData = struct('slotIndex', [], 'originalPosition', app.DraggingTile.Position);
                elseif ~isfield(app.DraggingTile.UserData, 'slotIndex')
                    app.DraggingTile.UserData.slotIndex = [];
                end
                
                % Clear slot if button is in one
                if isfield(app.DraggingTile.UserData, 'slotIndex') && ~isempty(app.DraggingTile.UserData.slotIndex)
                    slotIndex = app.DraggingTile.UserData.slotIndex;
                    app.wordSlots(slotIndex).Value = '';
                end
                
                % Store original position if not already stored
                if ~isfield(app.DraggingTile.UserData, 'originalPosition')
                    app.DraggingTile.UserData.originalPosition = app.DraggingTile.Position;
                end
                
                uistack(app.DraggingTile, 'top');  % Alternative to direct ZOrder access
                app.DraggingTile.FontWeight = 'bold';
                app.DraggingTile.BackgroundColor = [0.9 0.7 0.7];
                
                % Set up event handlers
                set(app.UIFigure, 'WindowButtonMotionFcn', @(src,event)app.dragTile(event));
                set(app.UIFigure, 'WindowButtonUpFcn', @(src,event)app.dropTile(event));
                
                disp(['Dragging ', app.DraggingTile.Text]);
            else
                disp('Invalid drag source');
            end
        end
        
        function dragTile(app, ~)
            if ~isempty(app.DraggingTile)
                % Get current mouse position relative to the figure
                currentPoint = app.UIFigure.CurrentPoint;
                
                % Keep the tile size the same
                tileWidth = app.DraggingTile.Position(3);
                tileHeight = app.DraggingTile.Position(4);
                
                % Center the tile on the mouse cursor
                newX = currentPoint(1) - (tileWidth / 2);
                newY = currentPoint(2) - (tileHeight / 2);
                
                % Make sure the tile stays within the figure bounds
                figWidth = app.UIFigure.Position(3);
                figHeight = app.UIFigure.Position(4);
                
                newX = max(0, min(newX, figWidth - tileWidth));
                newY = max(0, min(newY, figHeight - tileHeight));
                
                % Update the tile position
                app.DraggingTile.Position = [newX, newY, tileWidth, tileHeight];

                drawnow update;
            end
        end

        function dropTile(app, ~)
            if isempty(app.DraggingTile)
                return;
            end

            app.DraggingTile.FontWeight = 'normal';
            app.DraggingTile.BackgroundColor = [0.8 0.8 1];
    
            dropPos = app.UIFigure.CurrentPoint;
    
            if any(isnan(dropPos)) || any(isinf(dropPos))
                disp('Invalid drop position');
                set(app.UIFigure, 'WindowButtonMotionFcn', '');
                set(app.UIFigure, 'WindowButtonUpFcn', '');
                return;
            end
    
            placed = false;

            % Ensure UserData is a struct and slotIndex exists
            if ~isstruct(app.DraggingTile.UserData)
                app.DraggingTile.UserData = struct('slotIndex', [], 'originalPosition', app.DraggingTile.Position);
            elseif ~isfield(app.DraggingTile.UserData, 'slotIndex')
                app.DraggingTile.UserData.slotIndex = [];
            end

            % Clear previous slot if needed
            if ~isempty(app.DraggingTile.UserData.slotIndex)
                prevSlotIndex = app.DraggingTile.UserData.slotIndex;
                app.wordSlots(prevSlotIndex).Value = '';
                app.DraggingTile.UserData.slotIndex = [];
            end

            for i = 1:length(app.wordSlots)
                if app.isOverlapping(app.DraggingTile, app.wordSlots(i))
                    if ~isempty(app.wordSlots(i).Value)
                        disp(['Slot', num2str(i), 'is already occupied']);
                        continue;
                    end
            
                    slotPos = app.wordSlots(i).Position;

                    newX = slotPos(1) - 1; % Slight offset for better visibility
                    newY = slotPos(2) - 1;
            
                    app.DraggingTile.Position(1) = newX;
                    app.DraggingTile.Position(2) = newY;
            
                    uistack(app.DraggingTile, 'top');
                    app.DraggingTile.UserData.slotIndex = i; 
                                        
                    app.wordSlots(i).Value = app.DraggingTile.Text;

                    placed = true;

                    disp(['Placed in slot ', num2str(i)]);

                    app.wordSlots(i).FontColor = [1 1 1];
                    wordLength = length(app.PossibleWords{1});
                    rowIndex = ceil(i / wordLength);
                    
                    % Check row completion without calling checkWord directly
                    % Instead, check if all slots in this row are filled
                    rowStart = (rowIndex - 1) * wordLength + 1;
                    rowEnd = rowIndex * wordLength;
                    
                    rowComplete = true;
                    for j = rowStart:rowEnd
                        if j > length(app.wordSlots) || isempty(app.wordSlots(j).Value)
                            rowComplete = false;
                            break;
                        end
                    end
                    
                    if rowComplete
                        app.checkWord();
        
                    break;
                end
            end

            if ~placed
                % Return to original position
                if isfield(app.DraggingTile.UserData, 'originalPosition')
                    app.DraggingTile.Position = app.DraggingTile.UserData.originalPosition;
                end
                disp('Tile not placed in any valid slot');
            end
                
            set(app.UIFigure, 'WindowButtonMotionFcn', '');
            set(app.UIFigure, 'WindowButtonUpFcn', '');
    
            drawnow;
        end

        function letterButtonClicked(app, event)
            clickedButton = event.Source;
            
            % Ensure UserData is initialized properly
            if ~isstruct(clickedButton.UserData)
                clickedButton.UserData = struct('slotIndex', [], 'originalPosition', clickedButton.Position);
            elseif ~isfield(clickedButton.UserData, 'slotIndex')
                clickedButton.UserData.slotIndex = [];
            end
    
            % Check if this button is in a slot
            if ~isempty(clickedButton.UserData.slotIndex)
                slotIndex = clickedButton.UserData.slotIndex;
                % Clear the slot
                app.wordSlots(slotIndex).Value = '';

                if isfield(clickedButton.UserData, 'originalPosition')
                    clickedButton.Position = clickedButton.UserData.originalPosition;
                    clickedButton.UserData.slotIndex = []; % Clear the slot association
                    clickedButton.BackgroundColor = [0.8 0.8 1];
                    return; 
                end
            end
    
            % Start dragging the button
            app.startTileDrag(clickedButton);
        end

        function initializeGame(app)
            % Load dictionary
            app.loadDictionary();
    
            % Generate random letters
            app.generateRandomLetters();
    
            % Set up the word slots array
            app.wordSlots = [app.EditField, app.EditField2, app.EditField3, app.EditField4, app.EditField5];
    
            % Set up button click handlers
            app.setupButtonClickHandlers();
        end

        function setupButtonClickHandlers(app)
            app.letterButtons= [app.Button, app.Button2, app.Button3, app.Button4, app.Button5];
            for i = 1:length(app.letterButtons)
                app.letterButtons(i).ButtonPushedFcn = createCallbackFcn(app, @letterButtonClicked, true);
                app.letterButtons(i).BackgroundColor = [0.8 0.8 1]; % Light blue
                app.letterButtons(i).FontSize = 16;                 % Larger font
                app.letterButtons(i).FontWeight = 'bold';           % Bold text
                app.letterButtons(i).Tooltip = 'Drag me to a slot'; % Helpful tooltip
                
                % Initialize UserData as struct for all buttons
                app.letterButtons(i).UserData = struct('slotIndex', [], 'originalPosition', app.letterButtons(i).Position);
            end
        end

        function overlap = isOverlapping(app, tile, slot)
            if ~isobject(slot) || ~isvalid(slot)
                overlap = false;
                return;
            end

            tilePos = tile.Position;
            slotPos = slot.Position;

            tileCenter = [tilePos(1) + tilePos(3)/2, tilePos(2) + tilePos(4)/2];
            slotCenter = [slotPos(1) + slotPos(3)/2, slotPos(2) + slotPos(4)/2];

            distance = sqrt((tileCenter(1) - slotCenter(1))^2 + (tileCenter(2) - slotCenter(2))^2);

            overlap = (distance<30);
        end

        function resetTiles(app)
            letterButtons = [app.Button, app.Button2, app.Button3, app.Button4, app.Button5];
            
            % Clear all slots
            for i = 1:length(app.wordSlots)
                app.wordSlots(i).Value = '';
            end
            
            % Reset buttons to their original positions
            for i = 1:length(letterButtons)
                button = letterButtons(i);
                
                % Ensure UserData is a struct
                if ~isstruct(button.UserData)
                    button.UserData = struct('slotIndex', [], 'originalPosition', button.Position);
                elseif ~isfield(button.UserData, 'slotIndex')
                    button.UserData.slotIndex = [];
                end
                
                % If the button was in a slot, clear that association
                button.UserData.slotIndex = [];
                
                % Return to original position if stored
                if isfield(button.UserData, 'originalPosition')
                    button.Position = button.UserData.originalPosition;
                else
                    % Fallback positions if original positions weren't stored
                    baseX = 50 + (i-1)*55;
                    button.Position(1) = baseX;
                    button.Position(2) = 196;
                    
                    % Store this as the original position
                    button.UserData.originalPosition = button.Position;
                end

                button.FontWeight = 'normal';
                button.BackgroundColor = [0.8 0.8 1];
                
                % Make sure all buttons are visible
                uistack(button, 'top');
            end
            
            % Ensure UI updates immediately
            drawnow;
        end

        function resetGame(app)
            % Choose a new random word length
            wordLength = randi([4, 7]);
            
            % Generate new random letters
            app.generateRandomLetters(wordLength);
            
            % Clear found words
            app.FoundWords = {};
            
            % Update the game display
            app.WordCountdownLabel.Text = ['Words to find: ', num2str(app.PossibleWordsCount)];
            
            % Reset the UI
            drawnow;
        end


    function showGameModeScreen(app)
        % Hide the WelcomePanel and show the GameModePanel
        app.WelcomePanel.Visible = 'off';  % Hide WelcomePanel
        app.GameModePanel.Visible = 'on';  % Show GameModePanel
    end

    function showFiveNormalScreen(app)
        % Hide the GameModePanel and show the PlayPanel
        app.GameModePanel.Visible = 'off';  % Hide GameModePanel
        app.PlayPanel.Visible = 'on';       % Show PlayPanel (your game screen)
    end


        end
        
    end    


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
 app.showPanel(app.GameModePanel);
        end

        % Button pushed function: HowtoPlayButton
        function HowtoPlayButtonPushed(app, event)
            app.showPanel(app.InstructionsPanel);

        end

        % Button pushed function: BeginButton
        function BeginButtonPushed(app, event)
            app.showPanel(app.PlayPanel);

            app.EditField.Visible = 'off';
            app.EditField2.Visible = 'off';
            app.EditField3.Visible = 'off';
            app.EditField4.Visible = 'off';
            app.EditField5.Visible = 'off';

            app.Button.Visible = 'off';
            app.Button2.Visible = 'off';
            app.Button3.Visible = 'off';
            app.Button4.Visible = 'off';
            app.Button5.Visible = 'off';


            app.loadDictionary();
            wordLength = randi([4, 7]);
    app.generateRandomLetters(wordLength);
    
    % Update the submit button
    app.SubmitButton.Text = 'Submit Word';
    app.SubmitButton.BackgroundColor = [0.349, 0.8, 0.0196];
    app.SubmitButton.FontWeight = 'bold';
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            app.checkWord();
        end

        % Button pushed function: Button, Button2, Button3, Button4, 
        % ...and 1 other component
        function LetterTilesClicked(app, event)
        clickedButton = event.Source;
    
    if isfield(clickedButton.UserData, 'slotIndex') && ~isempty(clickedButton.UserData.slotIndex)
        slotIndex = clickedButton.UserData.slotIndex;
        
        % Clear the slot
        app.wordSlots(slotIndex).Value = '';

         clickedButton.UserData.slotIndex = [];

        
        % Return the button to its original position
        if isfield(clickedButton.UserData, 'originalPosition')
            clickedButton.Position = clickedButton.UserData.originalPosition;
            drawnow;
            return;
        end
        
        % Clear the slot association        
        % No need to start drag if we're just returning to original position
    end
    
    % If not in a slot, start dragging
    app.startTileDrag(clickedButton);
        end

        % Button pushed function: BacktoHomeButton
        function BacktoHomeButtonPushed(app, event)
            app.showPanel(app.GameModePanel);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WelcomePanel = uipanel(app.UIFigure);
            app.WelcomePanel.Title = 'Welcome Panel';
            app.WELCOMETOLabel = uilabel(app.WelcomePanel);
            app.WELCOMETOLabel.Text = 'WELCOME TO';
            app.Button_2 = uibutton(app.WelcomePanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'anagram.gif');
            app.Button_2.Text = '';
            app.ByKiaraIbanezDiazandOliviaWangLabel = uilabel(app.WelcomePanel);
            app.ByKiaraIbanezDiazandOliviaWangLabel.Text = 'By Kiara Ibanez-Diaz and Olivia Wang';
            app.CLPS0950March2025Label = uilabel(app.WelcomePanel);
            app.CLPS0950March2025Label.Text = 'CLPS 0950 March 2025';
            app.GameModePanel = uipanel(app.UIFigure);
            app.GameModePanel.Title = 'GameModePanel';
            app.GameModePanel.Visible = 'off';
            app.HomeLabel = uilabel(app.GameModePanel);
            app.HomeLabel.Text = 'Home';
            app.HowtoPlayButton = uibutton(app.GameModePanel, 'push');
            app.HowtoPlayButton.ButtonPushedFcn = createCallbackFcn(app, @HowtoPlayButtonPushed, true);
            app.HowtoPlayButton.Text = 'How to Play';
            app.BeginButton = uibutton(app.GameModePanel, 'push');
            app.BeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.BeginButton.Text = 'Begin';
            app.PlayPanel = uipanel(app.UIFigure);
            app.PlayPanel.Title = 'PlayPanel';
            app.PlayPanel.Visible = 'off';
            app.Button = uibutton(app.PlayPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button.Tag = 'LetterTiles';
            app.Button2 = uibutton(app.PlayPanel, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button2.Tag = 'LetterTiles';
            app.Button2.Text = 'Button2';
            app.Button3 = uibutton(app.PlayPanel, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button3.Tag = 'LetterTiles';
            app.Button3.Text = 'Button3';
            app.Button4 = uibutton(app.PlayPanel, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button4.Tag = 'LetterTiles';
            app.Button4.Text = 'Button4';
            app.Button5 = uibutton(app.PlayPanel, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button5.Tag = 'LetterTiles';
            app.Button5.Text = 'Button5';
            app.EditField = uieditfield(app.PlayPanel, 'text');
            app.EditField.Tag = 'WordSlots';
            app.EditField2 = uieditfield(app.PlayPanel, 'text');
            app.EditField2.Tag = 'WordSlots';
            app.EditField3 = uieditfield(app.PlayPanel, 'text');
            app.EditField3.Tag = 'WordSlots';
            app.EditField4 = uieditfield(app.PlayPanel, 'text');
            app.EditField4.Tag = 'WordSlots';
            app.EditField5 = uieditfield(app.PlayPanel, 'text');
            app.EditField5.Tag = 'WordSlots';
            app.SubmitButton = uibutton(app.PlayPanel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'SubmitButton';
            app.WordCountdownLabel = uilabel(app.PlayPanel);
            app.WordCountdownLabel.Text = 'Word Countdown:';
            app.InstructionsPanel = uipanel(app.UIFigure);
            app.InstructionsPanel.Title = 'InstructionsPanel';
            app.InstructionsPanel.Visible = 'off';
            app.BacktoHomeButton = uibutton(app.InstructionsPanel, 'push');
            app.BacktoHomeButton.ButtonPushedFcn = createCallbackFcn(app, @BacktoHomeButtonPushed, true);
            app.BacktoHomeButton.Text = 'Back to Home';
            app.TextArea = uitextarea(app.InstructionsPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Click and drag tiles from a series of scrambled letters. You will be given the count of possible valid words that can be created from rearranging the given letters. You must use all the tiles given. Once you enter a valid word, it will be added to a list on the side of your screen. Once you’ve entered all possible rearrangements of the letters, congratulations! You will be able to reset the game to generate another string of scrambled letters and play again! '};
            app.HowtoPlayLabel = uilabel(app.InstructionsPanel);
            app.HowtoPlayLabel.Text = 'How to Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = game1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end